classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        TabGroup           matlab.ui.container.TabGroup
        EfectosTab         matlab.ui.container.Tab
        AadirefectoButton  matlab.ui.control.Button
        Tree               matlab.ui.container.Tree
        Node               matlab.ui.container.TreeNode
        Node2              matlab.ui.container.TreeNode
        Node3              matlab.ui.container.TreeNode
        Node4              matlab.ui.container.TreeNode
        Tab2               matlab.ui.container.Tab
        InfoPanel          matlab.ui.container.Panel
        TextArea           matlab.ui.control.TextArea
        CargaButton        matlab.ui.control.Button
        GuardarButton      matlab.ui.control.Button
        RButton            matlab.ui.control.Button
        PButton            matlab.ui.control.Button
        PsButton           matlab.ui.control.Button
        SButton            matlab.ui.control.Button
        rButton            matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        data; % Description
        fs;
        info;
        recorder=audiorecorder;
        
    end
    
    methods (Access = private)
        
        function mostrarInfo(app,info)
            aux=split(string(info.Filename),'\');
            app.TextArea.Value=sprintf( ...
                'Fichero: %s\nDuración: %.2f s\nTasa de muestreo: %.2f Hz\nMuestras totales: %.0f\n', ...
                aux(length(aux)), ...
                string(info.Duration), ...
                string(app.info.SampleRate), ...
                string(app.info.TotalSamples));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaButton
        function CargaButtonPushed(app, event)
            file=uigetfile;
            [app.data, app.fs]=audioread(file);
            app.info=audioinfo(file);
            
            mostrarInfo(app,app.info);%función para mostrar informacion
            
            t=0:seconds(1/app.fs):seconds(app.info.Duration);
            t=t(1:end-1);
            
            plot(app.UIAxes,t,app.data);
            xlabel(app.UIAxes,'Tiempo [s]');
            ylabel(app.UIAxes,'Amplitud [V]');
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
             sound(app.data,app.fs);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            if app.recording==false
                app.recorder=audiorecorder;
                app.recording=true;
                record(recorder);
            elseif app.recording==true
                stop(recorder);
                app.recording=false;
                grabacion=getaudiodata(recorder);
                
                t=0:seconds(1/app.fs):seconds(app.info.Duration);
                t=t(1:end-1);
                
                plot(app.UIAxes,t,grabacion);
            end
            
        end

        % Value changed function: rButton
        function rButtonValueChanged(app, event)
            value = app.rButton.Value;
            
            if value==1
                record(app.recorder);
            elseif value==0
                stop(app.recorder);
                grabacion=getaudiodata(app.recorder);
                
                t=0:seconds(1/app.recorder.SampleRate):seconds(app.recorder.TotalSamples*(1/app.recorder.SampleRate));
                t=t(1:end-1);
                
                plot(app.UIAxes,t,grabacion);
                play(app.recorder);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.AadirefectoButton = uibutton(app.EfectosTab, 'push');
            app.AadirefectoButton.Text = 'Añadir efecto';
            app.Tree = uitree(app.EfectosTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.TextArea = uitextarea(app.InfoPanel);
            app.CargaButton = uibutton(app.UIFigure, 'push');
            app.CargaButton.ButtonPushedFcn = createCallbackFcn(app, @CargaButtonPushed, true);
            app.CargaButton.Text = 'Carga';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.Text = 'Guardar';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.PsButton = uibutton(app.UIFigure, 'push');
            app.PsButton.Text = 'Ps';
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.Text = 'S';
            app.rButton = uibutton(app.UIFigure, 'state');
            app.rButton.ValueChangedFcn = createCallbackFcn(app, @rButtonValueChanged, true);
            app.rButton.Text = 'r';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end